classdef mediatech < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatchandCommentarySoundAnnotatorUIFigure  matlab.ui.Figure
        HowToUseMenu                 matlab.ui.container.Menu
        EditAudioSoundSliderLabel    matlab.ui.control.Label
        EditAudioSoundSlider         matlab.ui.control.Slider
        EditAudioSoundSlider_2Label  matlab.ui.control.Label
        EditAudioSoundSlider_2       matlab.ui.control.Slider
        ImportAudioCommentaryButton  matlab.ui.control.Button
        ImportAudioCrowdNoiseButton  matlab.ui.control.Button
        ExportasAudioFileButton      matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        PlayButton                   matlab.ui.control.Button
        PauseButton                  matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        ComLabel                     matlab.ui.control.Label
        CrowdLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        % sets flags default value to false to check when to enable buttons
        % for use
        flag1 = false;
        flag2 = false;
    end
    
    methods (Access = public)
        % sets buttons to be enabled based on flag value
        function check(app)
            if (app.flag1 && app.flag2)
                set(app.PlayButton, "Enable", 'on');
                set(app.PauseButton, "Enable", 'on');
                set(app.StopButton, "Enable", 'on');
                set(app.EditAudioSoundSlider_2, "Enable", 'on');
                set(app.EditAudioSoundSlider, "Enable", 'on');
            elseif(app.flag1)
                set(app.EditAudioSoundSlider_2, "Enable", 'on');
            elseif(app.flag2)
                set(app.EditAudioSoundSlider, "Enable", 'on');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % sets buttons to not be used when application loads
            set(app.PlayButton, "Enable", 'off');
            set(app.PauseButton, "Enable", 'off');
            set(app.StopButton, "Enable", 'off');
            set(app.EditAudioSoundSlider, "Enable", 'off');
            set(app.EditAudioSoundSlider_2, "Enable", 'off');
            set(app.ExportasAudioFileButton, "Enable", 'off');
            
        end

        % Button pushed function: ImportAudioCommentaryButton
        function ImportAudioCommentaryButtonPushed(app, event)
            
            % gets file using uigetfile, creates a variable that creates a
            % full file of the path and file name, uses audio read to get
            % the samples from the temp file, then stores in global
            % variables
            global com1;
            global com2;
            global tempcom;
            
            [comfile, compath] = uigetfile({'*.mp3;'}, "Select MP3 File");
            temp = fullfile(compath, comfile);
            [y, Fs] = audioread(temp);
            figure(app.MatchandCommentarySoundAnnotatorUIFigure);
            com1 = y;
            com2 = Fs;
            tempcom = y;
            
            app.ComLabel.Text = "Imported: " + comfile;
            
            app.flag2 = true;
            app.check();
            
        end

        % Button pushed function: ImportAudioCrowdNoiseButton
        function ImportAudioCrowdNoiseButtonPushed(app, event)
            % gets file using uigetfile, creates a variable that creates a
            % full file of the path and file name, uses audio read to get
            % the samples from the temp file, then stores in global
            % variables
            global crowd1;
            global crowd2;
            global tempcrowd;
            
            [crowfile, crowpath] = uigetfile({'*mp3'}, "Select MP3 File");
            temp = fullfile(crowpath, crowfile);
            [y, Fs] = audioread(temp);
            
             %Needed to use this in order to stop the GUI minimising
            figure(app.MatchandCommentarySoundAnnotatorUIFigure);
            crowd1 = y;
            crowd2 = Fs;
            tempcrowd = y;
            
            app.CrowdLabel.Text = "Imported: " + crowfile;
            
            app.flag1 = true;
            app.check();
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % finds the mininum length of audio based on the two imported
            % tracks and mixes the sound in order to be played, once
            % played, the export button is set to be used by a user.
            global com1;
            global crowd1;
            global crowd2;
            global mix;
            
            lmax = min(length(crowd1), length(com1));
            mix = crowd1(1:lmax) + com1(1:lmax);
            sound(mix, crowd2);
            set(app.ExportasAudioFileButton, "Enable", 'on');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % clears sound method when stop button is pressed.
            clear sound;
        end

        % Button pushed function: ExportasAudioFileButton
        function ExportasAudioFileButtonPushed(app, event)
            % uses uiputfile to allow user to save in chosen location,
            % fullfile method is used to pack the files together (temp1 and
            % temp2) the audiowrite method is then used to save the mixed
            % audio
            global mix;
            global crowd2;
            
            [temp1, temp2] = uiputfile('.wav', 'Save Mixed Audio', 'mixedaudio.wav');
            new = fullfile(temp2, temp1);
            audiowrite(new, mix, crowd2);
            msgbox("Your audio: " + temp1 + " has been saved to: " + temp2 + ".");
            
        end

        % Value changed function: EditAudioSoundSlider
        function EditAudioSoundSliderValueChanged(app, event)
            % allows volume of match commentary to be adjusted based on
            % slider value, if more than 50, the sound is multiplied, if
            % less than 50, the sound is divided.
            value = app.EditAudioSoundSlider.Value;
            global com1;
            global tempcom;
            clear sound;
            vol = value / 10;
            
            com1 = tempcom;
            if value > 60
                com1 = com1*vol;
            elseif value < 40
                com1 = com1/vol;
            else
                com1 = tempcom;
            end
        end

        % Close request function: 
        % MatchandCommentarySoundAnnotatorUIFigure
        function MatchandCommentarySoundAnnotatorUIFigureCloseRequest(app, event)
            clear global;
            delete(app)
        end

        % Value changed function: EditAudioSoundSlider_2
        function EditAudioSoundSlider_2ValueChanged(app, event)
            % allows volume of crowd noise to be adjusted based on
            % slider value, if more than 50, the sound is multiplied, if
            % less than 50, the sound is divided.
            value = app.EditAudioSoundSlider_2.Value;
            global crowd1;
            global tempcrowd;
            clear sound;
            vol = value / 10;
            
            crowd1 = tempcrowd;
            if value > 60
                crowd1 = crowd1*vol;
            elseif value < 40
                crowd1 = crowd1/vol;
            else
                crowd1 = tempcrowd;
            end
        end

        % Menu selected function: HowToUseMenu
        function HowToUseMenuSelected(app, event)
            % When the user selected the how to use menu item, a message
            % box appears to showcase the user on how to use the system.
            msgbox(sprintf(['This Sound Annotator allows the user to mix two MP3 Audio files together\n' ...
                '- Import Crowd Noise and Match Audio using the selected import button' ...
                '- Play the mixed audio using the play button\n' ...
                '- If you would like to stop the audio then press the stop button\n' ...
                '- To adjust volume for each audio, adjust using the sliders\n' ...
                '- When satisfied, export the audio to save it as a wav file within your workspace']))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatchandCommentarySoundAnnotatorUIFigure = uifigure('Visible', 'off');
            app.MatchandCommentarySoundAnnotatorUIFigure.Name = 'Match and Commentary Sound Annotator';
            app.MatchandCommentarySoundAnnotatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @MatchandCommentarySoundAnnotatorUIFigureCloseRequest, true);
            app.HowToUseMenu = uimenu(app.MatchandCommentarySoundAnnotatorUIFigure);
            app.HowToUseMenu.MenuSelectedFcn = createCallbackFcn(app, @HowToUseMenuSelected, true);
            app.HowToUseMenu.Text = 'How To Use';
            app.EditAudioSoundSliderLabel = uilabel(app.MatchandCommentarySoundAnnotatorUIFigure);
            app.EditAudioSoundSliderLabel.Text = 'Edit Audio Sound';
            app.EditAudioSoundSlider = uislider(app.MatchandCommentarySoundAnnotatorUIFigure);
            app.EditAudioSoundSlider.Limits = [20 100];
            app.EditAudioSoundSlider.MajorTicks = [20 50 100];
            app.EditAudioSoundSlider.ValueChangedFcn = createCallbackFcn(app, @EditAudioSoundSliderValueChanged, true);
            app.EditAudioSoundSlider.MinorTicks = [0 35 50 75 100];
            app.EditAudioSoundSlider.Value = 50;
            app.EditAudioSoundSlider_2Label = uilabel(app.MatchandCommentarySoundAnnotatorUIFigure);
            app.EditAudioSoundSlider_2Label.Text = 'Edit Audio Sound';
            app.EditAudioSoundSlider_2 = uislider(app.MatchandCommentarySoundAnnotatorUIFigure);
            app.EditAudioSoundSlider_2.Limits = [20 100];
            app.EditAudioSoundSlider_2.MajorTicks = [20 50 100];
            app.EditAudioSoundSlider_2.ValueChangedFcn = createCallbackFcn(app, @EditAudioSoundSlider_2ValueChanged, true);
            app.EditAudioSoundSlider_2.MinorTicks = [0 35 50 75 100];
            app.EditAudioSoundSlider_2.Value = 50;
            app.ImportAudioCommentaryButton = uibutton(app.MatchandCommentarySoundAnnotatorUIFigure, 'push');
            app.ImportAudioCommentaryButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAudioCommentaryButtonPushed, true);
            app.ImportAudioCommentaryButton.Text = 'Import Audio Commentary';
            app.ImportAudioCrowdNoiseButton = uibutton(app.MatchandCommentarySoundAnnotatorUIFigure, 'push');
            app.ImportAudioCrowdNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAudioCrowdNoiseButtonPushed, true);
            app.ImportAudioCrowdNoiseButton.Text = 'Import Audio Crowd Noise';
            app.ExportasAudioFileButton = uibutton(app.MatchandCommentarySoundAnnotatorUIFigure, 'push');
            app.ExportasAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasAudioFileButtonPushed, true);
            app.ExportasAudioFileButton.Text = 'Export as Audio File';
            app.UIAxes = uiaxes(app.MatchandCommentarySoundAnnotatorUIFigure);
            title(app.UIAxes, 'Commentary and Crowd Noise Waves Visualisation')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [0 0.5 1];
            app.UIAxes.ZTickLabel = '';
            app.PlayButton = uibutton(app.MatchandCommentarySoundAnnotatorUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.MatchandCommentarySoundAnnotatorUIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.MatchandCommentarySoundAnnotatorUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ComLabel = uilabel(app.MatchandCommentarySoundAnnotatorUIFigure);
            app.ComLabel.Text = 'No Audio Selected';
            app.CrowdLabel = uilabel(app.MatchandCommentarySoundAnnotatorUIFigure);
            app.CrowdLabel.Text = 'No Audio Selected';
            % Show the figure after all components are created
            app.MatchandCommentarySoundAnnotatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mediatech

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatchandCommentarySoundAnnotatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatchandCommentarySoundAnnotatorUIFigure)
        end
    end
end